classdef LEDonoff < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        RGBLEDDropDownLabel        matlab.ui.control.Label
        RGBLEDDropDown             matlab.ui.control.DropDown
        ConnectButton              matlab.ui.control.Button
        DisconnectButton           matlab.ui.control.Button
        PhotocellReadingLabel      matlab.ui.control.Label
        PotentiometerReadingLabel  matlab.ui.control.Label
        PhotocellReadingText       matlab.ui.control.TextArea
        PotentiometerReadingText   matlab.ui.control.TextArea
        RedLEDPanel                matlab.ui.container.Panel
        RedOffButton               matlab.ui.control.Button
        RedOnButton                matlab.ui.control.Button
        YellowLEDPanel             matlab.ui.container.Panel
        YellowOffButton            matlab.ui.control.Button
        YellowOnButton             matlab.ui.control.Button
        GreenLEDPanel              matlab.ui.container.Panel
        GreenOffButton             matlab.ui.control.Button
        GreenOnButton              matlab.ui.control.Button
        BlueLEDPanel               matlab.ui.container.Panel
        BlueOffButton              matlab.ui.control.Button
        BlueOnButton               matlab.ui.control.Button
        PushbuttonCountersLabel    matlab.ui.control.Label
        RedCounter                 matlab.ui.control.Label
        YellowCounter              matlab.ui.control.Label
        GreenCounter               matlab.ui.control.Label
        BlueCounter                matlab.ui.control.Label
        UpdateButton               matlab.ui.control.Button
    end

    
    properties (Access = public)
        s = serial('COM10', 'Baudrate', 9600); % Description
        redCount = 0; % Description
        yellowCount = 0;
        greenCount = 0;
        blueCount = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            fopen(s);
            fprintf('Running\n');
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            fclose(s);
            fprintf('Closing\n');
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            fprintf('Updating Values\n');
            fprintf(s,'Update\n');
            pause(1);
            app.PhotocellReadingText.Value = ;
            app.PotentiometerReadingText.Value = ;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RGBLEDDropDownLabel = uilabel(app.UIFigure);
            app.RGBLEDDropDownLabel.Text = 'RGB LED';
            app.RGBLEDDropDown = uidropdown(app.UIFigure);
            app.RGBLEDDropDown.Items = {'Off', 'Blue', 'Green', 'Cyan', 'Red', 'Purple', 'Yellow', 'White'};
            app.RGBLEDDropDown.Value = 'Off';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.PhotocellReadingLabel = uilabel(app.UIFigure);
            app.PhotocellReadingLabel.Text = 'Photocell Reading';
            app.PotentiometerReadingLabel = uilabel(app.UIFigure);
            app.PotentiometerReadingLabel.Text = 'Potentiometer Reading';
            app.PhotocellReadingText = uitextarea(app.UIFigure);
            app.PotentiometerReadingText = uitextarea(app.UIFigure);
            app.RedLEDPanel = uipanel(app.UIFigure);
            app.RedLEDPanel.Title = 'Red LED';
            app.RedOffButton = uibutton(app.RedLEDPanel, 'push');
            app.RedOffButton.Text = 'Off';
            app.RedOnButton = uibutton(app.RedLEDPanel, 'push');
            app.RedOnButton.Text = 'On';
            app.YellowLEDPanel = uipanel(app.UIFigure);
            app.YellowLEDPanel.Title = 'Yellow LED';
            app.YellowOffButton = uibutton(app.YellowLEDPanel, 'push');
            app.YellowOffButton.Text = 'Off';
            app.YellowOnButton = uibutton(app.YellowLEDPanel, 'push');
            app.YellowOnButton.Text = 'On';
            app.GreenLEDPanel = uipanel(app.UIFigure);
            app.GreenLEDPanel.Title = 'Green LED';
            app.GreenOffButton = uibutton(app.GreenLEDPanel, 'push');
            app.GreenOffButton.Text = 'Off';
            app.GreenOnButton = uibutton(app.GreenLEDPanel, 'push');
            app.GreenOnButton.Text = 'On';
            app.BlueLEDPanel = uipanel(app.UIFigure);
            app.BlueLEDPanel.Title = 'Blue LED';
            app.BlueOffButton = uibutton(app.BlueLEDPanel, 'push');
            app.BlueOffButton.Text = 'Off';
            app.BlueOnButton = uibutton(app.BlueLEDPanel, 'push');
            app.BlueOnButton.Text = 'On';
            app.PushbuttonCountersLabel = uilabel(app.UIFigure);
            app.PushbuttonCountersLabel.Text = 'Pushbutton Counters';
            app.RedCounter = uilabel(app.UIFigure);
            app.RedCounter.Text = '0';
            app.YellowCounter = uilabel(app.UIFigure);
            app.YellowCounter.Text = '0';
            app.GreenCounter = uilabel(app.UIFigure);
            app.GreenCounter.Text = '0';
            app.BlueCounter = uilabel(app.UIFigure);
            app.BlueCounter.Text = '0';
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LEDonoff

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end