classdef MattMoran_Exam3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Communication_Lab              matlab.ui.Figure
        RGBLEDDropDownLabel            matlab.ui.control.Label
        RGBLEDDropDown                 matlab.ui.control.DropDown
        ConnectButton                  matlab.ui.control.Button
        DisconnectButton               matlab.ui.control.Button
        PhotocellReadingLabel          matlab.ui.control.Label
        PotentiometerReadingLabel      matlab.ui.control.Label
        RedLEDPanel                    matlab.ui.container.Panel
        RedOffButton                   matlab.ui.control.Button
        RedOnButton                    matlab.ui.control.Button
        YellowLEDPanel                 matlab.ui.container.Panel
        YellowOffButton                matlab.ui.control.Button
        YellowOnButton                 matlab.ui.control.Button
        GreenLEDPanel                  matlab.ui.container.Panel
        GreenOffButton                 matlab.ui.control.Button
        GreenOnButton                  matlab.ui.control.Button
        BlueLEDPanel                   matlab.ui.container.Panel
        BlueOffButton                  matlab.ui.control.Button
        BlueOnButton                   matlab.ui.control.Button
        PushbuttonCountersLabel        matlab.ui.control.Label
        RedCounter                     matlab.ui.control.Label
        YellowCounter                  matlab.ui.control.Label
        GreenCounter                   matlab.ui.control.Label
        PhotocellReading               matlab.ui.control.Label
        PotentiometerReading           matlab.ui.control.Label
        BlueCounter                    matlab.ui.control.Label
        UpdateButton                   matlab.ui.control.Button
        ClearPushbuttonCountersButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        redCount = 0; 
        yellowCount = 0;
        greenCount = 0;
        blueCount = 0;
        currentRBG = 'off';
        redpushed = "false"; 
        yellowpushed = "false";
        greenpushed = "false";
        bluepushed = "false";
        potvalue;
        photovalue;
        selectColor = 'w';
        shadowColor = [0.80,0.80,0.80];
      
    end
    
    properties (Access = private)
        sObj;
    end

    
    methods (Access = private)
        
        function connect(app)
            open_ports = instrfind('Type','serial','Status','open');
            if ~isempty(open_ports)
                 fclose(open_ports);
            end
        
            app.sObj = serial('COM10',...
                'Baudrate', 9600, ...
                'Terminator', 10,...
                'BytesAvailableFcnMode','Terminator',...
                'BytesAvailableFcn', @app.BytesRecieved );
            fopen(app.sObj);
            fprintf("\nConnected!\n");
            pause(1); 
        end
        function disconnect(app)
            fclose(app.sObj);
            fprintf("\nDisconnected!\n");
            pause(1);
        end
        function BytesRecieved(app, serialObj, eventData)
            message = fscanf(serialObj);
            message = strtrim(message);
            fprintf("Recieved: %s\n", message);
            %Complete match
            if(strcmpi(message, "PressedRed"))
                app.redCount = app.redCount + 1;
                app.redpushed = "true";
                app.updateButtonCounters();
            elseif(strcmpi(message, "PressedYellow"))
                app.yellowCount = app.yellowCount + 1;
                app.yellowpushed = "true";
                app.updateButtonCounters();
            elseif(strcmpi(message, "PressedGreen"))
                app.greenCount = app.greenCount + 1;
                app.greenpushed = "true";
                app.updateButtonCounters();
            elseif(strcmpi(message, "PressedBlue"))
                app.blueCount = app.blueCount + 1;
                app.bluepushed = "true";
                app.updateButtonCounters();
            end
            
            %partial match
            messageArray = split(message, " ");
            if(strcmpi(messageArray(1), "POT"))
                app.PotentiometerReading.Text = messageArray(2);
            elseif(strcmpi(messageArray(1), "PHOTO"))
                app.PhotocellReading.Text = messageArray(2);
            end
            
            
        end
        function updateButtonCounters(app)
            app.RedCounter.Text = num2str(app.redCount);
            app.YellowCounter.Text = num2str(app.yellowCount);
            app.GreenCounter.Text = num2str(app.greenCount);
            app.BlueCounter.Text = num2str(app.blueCount);
            app.redpushed = "false";
            app.yellowpushed = "false";
            app.greenpushed = "false";
            app.bluepushed = "false";
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.connect();
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.connect();
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.disconnect();
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            fprintf('\nUpdating Values\n');
            fprintf(app.sObj,'ADC\n');
            pause(1);
        end

        % Value changed function: RGBLEDDropDown
        function RGBLEDDropDownValueChanged(app, event)
            app.currentRBG = app.RGBLEDDropDown.Value;
            if(strcmp(app.currentRBG,"Off"))
                fprintf(app.sObj,'Off\n');
                pause(1);
            elseif(strcmp(app.currentRBG,"Blue"))
                fprintf(app.sObj,'Blue\n');
                pause(1);
            elseif(strcmp(app.currentRBG,"Green"))
                fprintf(app.sObj,'Green\n');
                pause(1);
            elseif(strcmp(app.currentRBG,"Cyan"))
                fprintf(app.sObj,'Cyan\n');
                pause(1);
            elseif(strcmp(app.currentRBG,"Red"))
                fprintf(app.sObj,'Red\n');
                pause(1);
            elseif(strcmp(app.currentRBG,"Purple")) 
                fprintf(app.sObj,'Purple\n');
                pause(1);
            elseif(strcmp(app.currentRBG,"Yellow"))
                fprintf(app.sObj,'Yellow\n');
                pause(1);
            elseif(strcmp(app.currentRBG,"White"))
                fprintf(app.sObj,'White\n');
                pause(1);
            end
        end

        % Button pushed function: RedOffButton
        function RedOffButtonPushed(app, event)
           fprintf(app.sObj,'ROFF\n');           
           app.RedOffButton.BackgroundColor = app.selectColor;
           app.RedOnButton.BackgroundColor = app.shadowColor;
        end

        % Button pushed function: RedOnButton
        function RedOnButtonPushed(app, event)
           fprintf(app.sObj,'RON\n');
           app.RedCounter.Text = num2str(app.redCount);
           app.RedOffButton.BackgroundColor = app.shadowColor;
           app.RedOnButton.BackgroundColor = app.selectColor;
        end

        % Button pushed function: YellowOffButton
        function YellowOffButtonPushed(app, event)
           fprintf(app.sObj,'YOFF\n');
           app.YellowOffButton.BackgroundColor = app.selectColor;
           app.YellowOnButton.BackgroundColor = app.shadowColor;
        end

        % Button pushed function: YellowOnButton
        function YellowOnButtonPushed(app, event)
           fprintf(app.sObj,'YON\n');
           app.YellowCounter.Text = num2str(app.yellowCount);
           app.YellowOffButton.BackgroundColor = app.shadowColor;
           app.YellowOnButton.BackgroundColor = app.selectColor;
        end

        % Button pushed function: GreenOffButton
        function GreenOffButtonPushed(app, event)
           fprintf(app.sObj,'GOFF\n');
           app.GreenOffButton.BackgroundColor = app.selectColor;
           app.GreenOnButton.BackgroundColor = app.shadowColor;
        end

        % Button pushed function: GreenOnButton
        function GreenOnButtonPushed(app, event)
           fprintf(app.sObj,'GON\n');
           app.GreenCounter.Text = num2str(app.greenCount);
           app.GreenOffButton.BackgroundColor = app.shadowColor;
           app.GreenOnButton.BackgroundColor = app.selectColor;
        end

        % Button pushed function: BlueOffButton
        function BlueOffButtonPushed(app, event)
           fprintf(app.sObj,'BOFF\n');
           app.BlueOffButton.BackgroundColor = app.selectColor;
           app.BlueOnButton.BackgroundColor = app.shadowColor;           
        end

        % Button pushed function: BlueOnButton
        function BlueOnButtonPushed(app, event)
           fprintf(app.sObj,'BON\n');
           app.BlueCounter.Text = num2str(app.blueCount);
           app.BlueOffButton.BackgroundColor = app.shadowColor;
           app.BlueOnButton.BackgroundColor = app.selectColor; 
        end

        % Close request function: Communication_Lab
        function Communication_LabCloseRequest(app, event)
            app.disconnect();    
            delete(app)
            
        end

        % Button pushed function: ClearPushbuttonCountersButton
        function ClearPushbuttonCountersButtonPushed(app, event)
            app.redCount = 0;
            app.yellowCount = 0;
            app.greenCount = 0;
            app.blueCount = 0;
            app.updateButtonCounters();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Communication_Lab = uifigure('Visible', 'off');
            app.Communication_Lab.Name = 'UI Figure';
            app.Communication_Lab.CloseRequestFcn = createCallbackFcn(app, @Communication_LabCloseRequest, true);
            app.RGBLEDDropDownLabel = uilabel(app.Communication_Lab);
            app.RGBLEDDropDownLabel.Text = 'RGB LED';
            app.RGBLEDDropDown = uidropdown(app.Communication_Lab);
            app.RGBLEDDropDown.Items = {'Off', 'Blue', 'Green', 'Cyan', 'Red', 'Purple', 'Yellow', 'White'};
            app.RGBLEDDropDown.ValueChangedFcn = createCallbackFcn(app, @RGBLEDDropDownValueChanged, true);
            app.RGBLEDDropDown.Value = 'Off';
            app.ConnectButton = uibutton(app.Communication_Lab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.Communication_Lab, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.PhotocellReadingLabel = uilabel(app.Communication_Lab);
            app.PhotocellReadingLabel.Text = 'Photocell Reading';
            app.PotentiometerReadingLabel = uilabel(app.Communication_Lab);
            app.PotentiometerReadingLabel.Text = 'Potentiometer Reading';
            app.RedLEDPanel = uipanel(app.Communication_Lab);
            app.RedLEDPanel.Title = 'Red LED';
            app.RedOffButton = uibutton(app.RedLEDPanel, 'push');
            app.RedOffButton.ButtonPushedFcn = createCallbackFcn(app, @RedOffButtonPushed, true);
            app.RedOffButton.Text = 'Off';
            app.RedOnButton = uibutton(app.RedLEDPanel, 'push');
            app.RedOnButton.ButtonPushedFcn = createCallbackFcn(app, @RedOnButtonPushed, true);
            app.RedOnButton.Text = 'On';
            app.YellowLEDPanel = uipanel(app.Communication_Lab);
            app.YellowLEDPanel.Title = 'Yellow LED';
            app.YellowOffButton = uibutton(app.YellowLEDPanel, 'push');
            app.YellowOffButton.ButtonPushedFcn = createCallbackFcn(app, @YellowOffButtonPushed, true);
            app.YellowOffButton.Text = 'Off';
            app.YellowOnButton = uibutton(app.YellowLEDPanel, 'push');
            app.YellowOnButton.ButtonPushedFcn = createCallbackFcn(app, @YellowOnButtonPushed, true);
            app.YellowOnButton.Text = 'On';
            app.GreenLEDPanel = uipanel(app.Communication_Lab);
            app.GreenLEDPanel.Title = 'Green LED';
            app.GreenOffButton = uibutton(app.GreenLEDPanel, 'push');
            app.GreenOffButton.ButtonPushedFcn = createCallbackFcn(app, @GreenOffButtonPushed, true);
            app.GreenOffButton.Text = 'Off';
            app.GreenOnButton = uibutton(app.GreenLEDPanel, 'push');
            app.GreenOnButton.ButtonPushedFcn = createCallbackFcn(app, @GreenOnButtonPushed, true);
            app.GreenOnButton.Text = 'On';
            app.BlueLEDPanel = uipanel(app.Communication_Lab);
            app.BlueLEDPanel.Title = 'Blue LED';
            app.BlueOffButton = uibutton(app.BlueLEDPanel, 'push');
            app.BlueOffButton.ButtonPushedFcn = createCallbackFcn(app, @BlueOffButtonPushed, true);
            app.BlueOffButton.Text = 'Off';
            app.BlueOnButton = uibutton(app.BlueLEDPanel, 'push');
            app.BlueOnButton.ButtonPushedFcn = createCallbackFcn(app, @BlueOnButtonPushed, true);
            app.BlueOnButton.Text = 'On';
            app.PushbuttonCountersLabel = uilabel(app.Communication_Lab);
            app.PushbuttonCountersLabel.Text = 'Pushbutton Counters';
            app.RedCounter = uilabel(app.Communication_Lab);
            app.RedCounter.Text = '0';
            app.YellowCounter = uilabel(app.Communication_Lab);
            app.YellowCounter.Text = '0';
            app.GreenCounter = uilabel(app.Communication_Lab);
            app.GreenCounter.Text = '0';
            app.BlueCounter = uilabel(app.Communication_Lab);
            app.BlueCounter.Text = '0';
            app.UpdateButton = uibutton(app.Communication_Lab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.PhotocellReading = uilabel(app.Communication_Lab);
            app.PhotocellReading.Text = '0';
            app.PotentiometerReading = uilabel(app.Communication_Lab);
            app.PotentiometerReading.Text = '0';
            app.ClearPushbuttonCountersButton = uibutton(app.Communication_Lab, 'push');
            app.ClearPushbuttonCountersButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPushbuttonCountersButtonPushed, true);
            app.ClearPushbuttonCountersButton.Text = {'Clear '; 'Pushbutton '; 'Counters'};
            % Show the figure after all components are created
            app.Communication_Lab.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MattMoran_Exam3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Communication_Lab)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Communication_Lab)
        end
    end
end