classdef DinoGame < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SelectPlayerDropDownLabel  matlab.ui.control.Label
        SelectPlayerDropDown       matlab.ui.control.DropDown
        BackgroundImg              matlab.ui.control.Image
        ResetHighScoreButton       matlab.ui.control.Button
        Dino                       matlab.ui.control.Image
        GameOverImg                matlab.ui.control.Image
        GameOverText               matlab.ui.control.Label
        Obstacle1                  matlab.ui.control.Image
        Obstacle2                  matlab.ui.control.Image
        ConnectButton              matlab.ui.control.Button
        DisconnectButton           matlab.ui.control.Button
        ScoreLabel                 matlab.ui.control.Label
        ScoreText                  matlab.ui.control.Label
        DinoRunbyMattMoranLabel    matlab.ui.control.Label
        HighScoreLabel             matlab.ui.control.Label
        HighScoreText              matlab.ui.control.Label
    end

    
    properties (Access = public)
                 obstacle_positions = [
           490  340     35      80;
           455  340	35	80;
           420  340	35	80;
           385	340	35	80;
           350	340	35	80;
           315	340	35	80;
           280	340	35	80;
           245	340	35	80;
           210	340	35	80;
           175	340	35	80;
           140	340	35	80;
           105	340	35	80;
           70	340	35	80;
           35	340	35	80;
           490	410	35	80;
           455	410	35	80;
           420	410	35	80;
           385	410	35	80;
           350	410	35	80;
           315	410	35	80;
           280	410	35	80;
           245	410	35	80;
           210	410	35	80;
           175	410	35	80;
           140	410	35	80;
           105	410	35	80;
           70	410	35	80;
           35	410	35	80;];
       
           Dino_positions = [
           70  385      35      80;
           70  350	35	80;
           70  315	35	80;];
       
        highScoreP1 = 0;
        highScoreP2 = 0;
        currentScore = 0;
        gameOver = "false";
        playerOption = "Player 1";
        dinoPos = 2;
        Obs1Pos = 1;
        Obs2Pos = 1;
        PhotocellReading;
        jumpPressed = "false";
        duckPressed = "false";
        difficulty = 1;
        night = "off";
        FLAG = 0;
        ob2Visable = "false";
        Ob2Type = "Cactus";
    end
    
    properties (Access = private)
        sObj; 
    end
    
    methods (Access = private)
        function connect(app)
            open_ports = instrfind('Type','serial','Status','open');
            if ~isempty(open_ports)
                 fclose(open_ports);
            end
        
            app.sObj = serial('COM10',...
                'Baudrate', 9600, ...
                'Terminator', 10,...
                'BytesAvailableFcnMode','Terminator',...
                'BytesAvailableFcn', @app.BytesRecieved );
            fopen(app.sObj);
            fprintf("\nConnected!\n");
            pause(1); 
            
        end
        function disconnect(app)
            fclose(app.sObj);
            fprintf("\nDisconnected!\n");
            pause(1);
        end
        function BytesRecieved(app, serialObj, eventData)
            message = fscanf(serialObj);
            message = strtrim(message);
            fprintf("Recieved: %s\n", message);
            %Complete match
            if(strcmpi(message, "PressedDuck"))
                app.duckPressed = "true";
                app.dinoDuck();
            elseif(strcmpi(message, "PressedJump"))
                app.jumpPressed = "true";
                app.dinoJump();
            elseif(strcmpi(message, "PressedStart"))
                app.FLAG = 0;
                app.ob2Visable = "false"
                app.gameOver = "false";
                app.startMovement();
            elseif(strcmpi(message, "Dark"))
                app.darkMode();
                pause(.1);
            elseif(strcmpi(message, "Light"))                
                app.lightMode();
                pause(.1);
            end
            
            %partial match
            
            messageArray = split(message, " ")
           
            if(strcmpi(messageArray(1), "Score"))
                app.currentScore = cell2mat(messageArray(2));
                app.ScoreText.Text = app.currentScore;
                app.updateMovement();
            elseif(strcmpi(messageArray(1), "tempScore"))
                if(strcmp(app.playerOption,"Player 1"))
                    app.highScoreP1 = cell2mat(messageArray(2));
                    app.HighScoreText.Text = num2str(app.highScoreP1);
                elseif(strcmp(app.playerOption,"Player 2"))
                    app.highScoreP2 = cell2mat(messageArray(2));
                    app.HighScoreText.Text = num2str(app.highScoreP2);
                end
         
            end
            
            
        end
        function updateScores(app)
            if(strcmp(app.playerOption,"Player 1"))
                if(app.currentScore > app.highScoreP1)
                    app.highScoreP1 = app.currentScore;
                    app.HighScoreText.Text = num2str(app.highScoreP1);
                end
            elseif(strcmp(app.playerOption,"Player 2"))
                if(app.currentScore > app.highScoreP2)
                    app.highScoreP2 = app.currentScore;
                    app.HighScoreText.Text = num2str(app.highScoreP2);
                end                             
            end
        end
        function updateMovement(app)
            if(app.Obs1Pos == 6)
                app.Obstacle2.Visible = "on";
                app.ob2Visable = "True";
            end
            if(app.Obs1Pos == 14)
                app.Obs1Pos = 1;
            end
            if(app.Obs2Pos == 14)
                app.chooseNextObstacle()
                if(strcmpi(app.Ob2Type, "Cactus" ))
                    app.Obs2Pos = 1;
                    app.Obstacle2.Position = app.obstacle_positions(1,:);
                elseif(strcmpi(app.Ob2Type, "flying" ))
                    app.Obs2Pos = 15;
                    app.Obstacle2.Position = app.obstacle_positions(15,:);
                end
            elseif(app.Obs2Pos == 28)
                app.chooseNextObstacle()
                if(strcmpi(app.Ob2Type, "Cactus" ))
                    app.Obs2Pos = 1;
                    app.Obstacle2.Position = app.obstacle_positions(1,:);
                elseif(strcmpi(app.Ob2Type, "flying" ))
                    app.Obs2Pos = 15;
                    app.Obstacle2.Position = app.obstacle_positions(15,:);
                end
                
            end
            if(app.FLAG ~= 1)
               
                app.Obstacle1.Position = app.obstacle_positions(app.Obs1Pos + 1,:);
                if(strcmpi(app.ob2Visable, "True"))
                    app.Obstacle2.Position = app.obstacle_positions(app.Obs2Pos + 1,:);
                end
                app.checkCollisions();


            elseif(app.FLAG == 1)
                app.stopTimer(); 
            
            end            
            app.Obs1Pos = app.Obs1Pos + 1;
            app.Obs2Pos = app.Obs2Pos + 1;
            
            
        end
        function startMovement(app)
            app.Dino.Position = app.Dino_positions(2,:);
            app.Obstacle1.Visible = 'on';
            app.Obs1Pos = 6;
            app.Obs2Pos = 1;
            app.Obstacle1.Position = app.obstacle_positions(app.Obs1Pos,:);
            app.Obstacle2.Visible = "off";
            app.Obstacle2.Position = app.obstacle_positions(app.Obs2Pos,:);
            app.gameOver = "false";
            app.GameOverImg.Visible = "off";
            app.GameOverText.Visible = "off";
        end
        function dinoJump(app)
            if(app.dinoPos == 1)
                app.Dino.Position = app.Dino_positions(1,:);
                app.dinoPos = 1;
            elseif(app.dinoPos == 2)
                app.Dino.Position = app.Dino_positions(1,:);
                app.dinoPos = 1;
            elseif(app.dinoPos == 3)
                app.Dino.Position = app.Dino_positions(2,:);
                app.dinoPos = 2;
            end
            pause(.1)
        end
        function dinoDuck(app)
            if(app.dinoPos == 1)
                app.Dino.Position = app.Dino_positions(2,:);
                app.dinoPos = 2;
            elseif(app.dinoPos == 2)
                app.Dino.Position = app.Dino_positions(3,:);
                app.dinoPos = 3;
            elseif(app.dinoPos == 3)
                app.Dino.Position = app.Dino_positions(3,:);
                app.dinoPos = 3;
            end
            pause(.1)
        end
        function stopTimer(app)
            fprintf(app.sObj, "Stop\n");
            app.gameOver = "True";
            app.GameOverImg.Visible = 'on';
            app.GameOverText.Visible = 'on';
            app.updateScores();
            app.currentScore = 0;
            if(strcmp(app.playerOption,"Player 1"))
                app.HighScoreText.Text = num2str(app.highScoreP1);
                app.ScoreText.Text = num2str(app.currentScore);
            elseif(strcmp(app.playerOption,"Player 2"))
                app.ScoreText.Text = num2str(app.currentScore);
                app.HighScoreText.Text = num2str(app.highScoreP2);                                           
            end
            
        end
        function lightMode(app)
            app.night = "off";
            app.BackgroundImg.ImageSource = 'background.png';
            app.Dino.ImageSource = 'Dino.png';
            app.Obstacle1.ImageSource = 'Cactus.JPG';
            if(strcmpi(app.Obstacle2.ImageSource,'nightCactus.JPG'))
                app.Obstacle2.ImageSource = 'Cactus.JPG';
            elseif(strcmpi(app.Obstacle2.ImageSource,'nightflying.JPG'))
                app.Obstacle2.ImageSource = 'flying.JPG';
            end
        end
        function darkMode(app)
            app.BackgroundImg.ImageSource = 'nightBackground.png';
            app.night = "on";
            app.Dino.ImageSource = 'nightDino.JPG';
            app.Obstacle1.ImageSource = 'nightCactus.JPG';
            if(strcmpi(app.Obstacle2.ImageSource,'Cactus.JPG'))
                app.Obstacle2.ImageSource = 'nightCactus.JPG';
            elseif(strcmpi(app.Obstacle2.ImageSource,'flying.JPG'))
                app.Obstacle2.ImageSource = 'nightflying.JPG';
            end
        end
        function checkCollisions(app)
            if(app.Obstacle1.Position == app.obstacle_positions(13,:))                    
                if(app.dinoPos ~= 1)
                    app.FLAG = 1;
                end
            elseif(app.Obstacle1.Position == app.obstacle_positions(27,:)) 
                if(app.dinoPos ~= 3)
                    app.FLAG = 1;
                end
            elseif(app.Obstacle2.Position == app.obstacle_positions(13,:))
                if(app.dinoPos ~= 1)
                    app.FLAG = 1;
                end
            elseif(app.Obstacle2.Position == app.obstacle_positions(27,:))
                if(app.dinoPos ~= 3)
                    app.FLAG = 1;
                end
            end
        end
        function chooseNextObstacle(app)
            nextObstacle = randi(100);
            if(nextObstacle < 50)
                if(strcmpi(app.night, "on"))
                    app.Obstacle2.ImageSource = 'nightflying.JPG';
                elseif(strcmpi(app.night, "off"))
                    app.Obstacle2.ImageSource = 'flying.JPG';
                end
                app.Ob2Type = "flying";
            elseif(nextObstacle >= 50)
                if(strcmpi(app.night, "on"))
                    app.Obstacle2.ImageSource = 'nightCactus.JPG';
                elseif(strcmpi(app.night, "off"))
                    app.Obstacle2.ImageSource = 'Cactus.JPG';
                end
                app.Ob2Type = "Cactus";
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc            
            app.connect();
            app.Dino.Position = app.Dino_positions(2,:);
            app.dinoPos = 2;
            app.Obstacle1.Visible = "off";
            app.Obstacle2.Visible = "off";
        end

        % Button pushed function: ResetHighScoreButton
        function ResetHighScoreButtonPushed(app, event)
            app.highScoreP1 = 0;
            app.highScoreP2 = 0;
            app.HighScoreText.Text = '0';
        end

        % Value changed function: SelectPlayerDropDown
        function SelectPlayerDropDownValueChanged(app, event)
            app.playerOption = app.SelectPlayerDropDown.Value;
            if(strcmp(app.playerOption,"Player 1"))
                fprintf(app.sObj,'Player1\n');
            elseif(strcmp(app.playerOption,"Player 2"))
                fprintf(app.sObj,'Player2\n');               
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.stopTimer();   
            app.disconnect(); 
            delete(app)
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.connect();
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.stopTimer();
            app.disconnect();
            
        end

        % Callback function
        function jumpButtonPushed(app, event)
            app.dinoJump();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SelectPlayerDropDownLabel = uilabel(app.UIFigure);
            app.SelectPlayerDropDownLabel.Text = 'Select Player';
            app.SelectPlayerDropDown = uidropdown(app.UIFigure);
            app.SelectPlayerDropDown.Items = {'Player 1', 'Player 2'};
            app.SelectPlayerDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectPlayerDropDownValueChanged, true);
            app.SelectPlayerDropDown.Value = 'Player 1';
            app.BackgroundImg = uiimage(app.UIFigure);
            app.BackgroundImg.ImageSource = 'background.png';
            app.ResetHighScoreButton = uibutton(app.UIFigure, 'push');
            app.ResetHighScoreButton.ButtonPushedFcn = createCallbackFcn(app, @ResetHighScoreButtonPushed, true);
            app.ResetHighScoreButton.Text = 'Reset High Score';
            app.Dino = uiimage(app.UIFigure);
            app.Dino.ImageSource = 'Dino.PNG';
            app.GameOverImg = uiimage(app.UIFigure);
            app.GameOverImg.Visible = 'off';
            app.GameOverImg.ImageSource = 'GameOver.PNG';
            app.GameOverText = uilabel(app.UIFigure);
            app.GameOverText.Visible = 'off';
            app.GameOverText.Text = {'Press Start Button OnBoard'; 'To Play Again'};
            app.Obstacle1 = uiimage(app.UIFigure);
            app.Obstacle1.ImageSource = 'Cactus.JPG';
            app.Obstacle2 = uiimage(app.UIFigure);
            app.Obstacle2.ImageSource = 'Cactus.JPG';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.ScoreLabel = uilabel(app.UIFigure);
            app.ScoreLabel.Text = 'Score';
            app.ScoreText = uilabel(app.UIFigure);
            app.ScoreText.Text = '0';
            app.DinoRunbyMattMoranLabel = uilabel(app.UIFigure);
            app.DinoRunbyMattMoranLabel.Text = 'Dino Run - by Matt Moran';
            app.HighScoreLabel = uilabel(app.UIFigure);
            app.HighScoreLabel.Text = 'High Score';
            app.HighScoreText = uilabel(app.UIFigure);
            app.HighScoreText.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DinoGame

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end